classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField           matlab.ui.control.NumericEditField
        EditField_7         matlab.ui.control.NumericEditField
        EditField_13        matlab.ui.control.NumericEditField
        EditField_19        matlab.ui.control.NumericEditField
        EditField_25        matlab.ui.control.NumericEditField
        X1EditFieldLabel    matlab.ui.control.Label
        EditField_2         matlab.ui.control.NumericEditField
        X1EditField_2Label  matlab.ui.control.Label
        EditField_8         matlab.ui.control.NumericEditField
        X1EditField_3Label  matlab.ui.control.Label
        EditField_14        matlab.ui.control.NumericEditField
        X1EditField_4Label  matlab.ui.control.Label
        EditField_20        matlab.ui.control.NumericEditField
        X1EditField_5Label  matlab.ui.control.Label
        EditField_26        matlab.ui.control.NumericEditField
        X2EditFieldLabel    matlab.ui.control.Label
        EditField_3         matlab.ui.control.NumericEditField
        X2EditField_2Label  matlab.ui.control.Label
        EditField_9         matlab.ui.control.NumericEditField
        X2EditField_3Label  matlab.ui.control.Label
        EditField_15        matlab.ui.control.NumericEditField
        X2EditField_4Label  matlab.ui.control.Label
        EditField_21        matlab.ui.control.NumericEditField
        X2EditField_5Label  matlab.ui.control.Label
        EditField_27        matlab.ui.control.NumericEditField
        X3EditFieldLabel    matlab.ui.control.Label
        EditField_4         matlab.ui.control.NumericEditField
        X3EditField_2Label  matlab.ui.control.Label
        EditField_10        matlab.ui.control.NumericEditField
        X3EditField_3Label  matlab.ui.control.Label
        EditField_16        matlab.ui.control.NumericEditField
        X3EditField_4Label  matlab.ui.control.Label
        EditField_22        matlab.ui.control.NumericEditField
        X3EditField_5Label  matlab.ui.control.Label
        EditField_28        matlab.ui.control.NumericEditField
        X4EditFieldLabel    matlab.ui.control.Label
        EditField_5         matlab.ui.control.NumericEditField
        X4EditField_2Label  matlab.ui.control.Label
        EditField_11        matlab.ui.control.NumericEditField
        X4EditField_3Label  matlab.ui.control.Label
        EditField_17        matlab.ui.control.NumericEditField
        X4EditField_4Label  matlab.ui.control.Label
        EditField_23        matlab.ui.control.NumericEditField
        X4EditField_5Label  matlab.ui.control.Label
        EditField_29        matlab.ui.control.NumericEditField
        X5EditFieldLabel    matlab.ui.control.Label
        EditField_6         matlab.ui.control.NumericEditField
        X5EditField_2Label  matlab.ui.control.Label
        EditField_12        matlab.ui.control.NumericEditField
        X5EditField_3Label  matlab.ui.control.Label
        EditField_18        matlab.ui.control.NumericEditField
        X5EditField_4Label  matlab.ui.control.Label
        EditField_24        matlab.ui.control.NumericEditField
        X5EditField_5Label  matlab.ui.control.Label
        EditField_30        matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        X2Button            matlab.ui.control.Button
        X3Button            matlab.ui.control.Button
        X4Button            matlab.ui.control.Button
        X5Button            matlab.ui.control.Button
        Label_7             matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        Label_9             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: X2Button
        function X2ButtonPushed(app, event)
            s1=app.EditField.Value;
            s2=app.EditField_2.Value;
            s6=app.EditField_6.Value;
            s7=app.EditField_7.Value;
            s8=app.EditField_8.Value;
            s12=app.EditField_12.Value;
            a1=s1;
            a2=s2;
            a6=s6;
            a7=s7;
            a8=s8;
            a12=s12;
            c1=cat(2,a1,a2);
            v1=cat(2,a7,a8);
            n1=cat(1,a1,a7);
            m1=cat(1,a2,a8);
            j=cat(1,a6,a12);
            st2x2=cat(1,c1,v1);
            dfirst=cat(2,j,m1);
            dsecond=cat(2,n1,j);
            r=det(st2x2);
            r1=det(dfirst);
            r2=det(dsecond);
            x=st2x2\j;
            x1=r1/r;
            x2=r2/r;
            set(app.Label, 'Text', num2str(x1));
            set(app.Label_2, 'Text', num2str(x2));
            set(app.Label_6, 'Text', num2str(x'));
        end

        % Button pushed function: X3Button
        function X3ButtonPushed(app, event)
            s1=app.EditField.Value;
            s2=app.EditField_2.Value;
            s3=app.EditField_3.Value;
            s6=app.EditField_6.Value;
            s7=app.EditField_7.Value;
            s8=app.EditField_8.Value;
            s9=app.EditField_9.Value;
            s12=app.EditField_12.Value;
            s13=app.EditField_13.Value;
            s14=app.EditField_14.Value;
            s15=app.EditField_15.Value;
            s18=app.EditField_18.Value;
            a1=s1;
            a2=s2;
            a3=s3;
            a6=s6;
            a7=s7;
            a8=s8;
            a9=s9;
            a12=s12;
            a13=s13;
            a14=s14;
            a15=s15;
            a18=s18;
            c1=cat(2,a1,a2);
            c2=cat(2,c1,a3);
            v1=cat(2,a7,a8);
            v2=cat(2,v1,a9);
            b1=cat(2,a13,a14);
            b2=cat(2,b1,a15);
            q=cat(1,c2,v2);
            w=cat(1,q,b2);
            f=cat(1,a6,a12);
            g=cat(1,f,a18);
            d=cat(1,a1,a7);
            d1=cat(1,d,a13);
            k=cat(1,a2,a8);
            k1=cat(1,k,a14);
            p=cat(1,a3,a9);
            p1=cat(1,p,a15);
            dfirst=cat(2,g,k1);
            dfirst1=cat(2,dfirst,p1);
            dsecond=cat(2,d1,g);
            dsecond1=cat(2,dsecond,p1);
            dthird=cat(2,d1,k1);
            dthird1=cat(2,dthird,g);
            r=det(w);
            r1=det(dfirst1);
            r2=det(dsecond1);
            r3=det(dthird1);
            x1=r1/r;
            x2=r2/r;
            x3=r3/r;
            x=w\g;
            set(app.Label, 'Text', num2str(x1));
            set(app.Label_2, 'Text', num2str(x2));
            set(app.Label_3, 'Text', num2str(x3));
            set(app.Label_6, 'Text', num2str(x'));
        end

        % Button pushed function: X4Button
        function X4ButtonPushed(app, event)
                        s1=app.EditField.Value;
s2=app.EditField_2.Value;
s3=app.EditField_3.Value;
s4=app.EditField_4.Value;

s6=app.EditField_6.Value;
s7=app.EditField_7.Value;
s8=app.EditField_8.Value;
s9=app.EditField_9.Value;
s10=app.EditField_10.Value;

s12=app.EditField_12.Value;
s13=app.EditField_13.Value;
s14=app.EditField_14.Value;
s15=app.EditField_15.Value;
s16=app.EditField_16.Value;

s18=app.EditField_18.Value;
s19=app.EditField_19.Value;
s20=app.EditField_20.Value;
s21=app.EditField_21.Value;
s22=app.EditField_22.Value;

s24=app.EditField_24.Value;

a1=s1;
a2=s2;
a3=s3;
a4=s4;
a6=s6;
a7=s7;
a8=s8;
a9=s9;
a10=s10;
a12=s12;
a13=s13;
a14=s14;
a15=s15;
a16=s16;
a18=s18;
a19=s19;
a20=s20;
a21=s21;
a22=s22;
a24=s24;

c1=cat(2,a1,a2);
c2=cat(2,c1,a3);
c3=cat(2,c2,a4);
v1=cat(2,a7,a8);
v2=cat(2,v1,a9);
v3=cat(2,v2,a10);
b1=cat(2,a13,a14);
b2=cat(2,b1,a15);
b3=cat(2,b2,a16);
n1=cat(2,a19,a20);
n2=cat(2,n1,a21);
n3=cat(2,n2,a22);
q=cat(1,c3,v3);
w=cat(1,q,b3);
e=cat(1,w,n3);
f=cat(1,a6,a12);
g=cat(1,f,a18);
h=cat(1,g,a24);

d=cat(1,a1,a7);
d1=cat(1,d,a13);
d2=cat(1,d1,a19);

k=cat(1,a2,a8);
k1=cat(1,k,a14);
k2=cat(1,k1,a20);

p=cat(1,a3,a9);
p1=cat(1,p,a15);
p2=cat(1,p1,a21);

t=cat(1,a4,a10);
t1=cat(1,t,a16);
t2=cat(1,t1,a22);


dfirst=cat(2,h,k2);
dfirst1=cat(2,dfirst,p2);
dfirst2=cat(2,dfirst1,t2);
dsecond=cat(2,d2,h);
dsecond1=cat(2,dsecond,p2);
dsecond2=cat(2,dsecond1,t2);
dthird=cat(2,d2,k2);
dthird1=cat(2,dthird,h);
dthird2=cat(2,dthird1,t2);

dfourth=cat(2,d2,k2);
dfourth1=cat(2,dfourth,p2);
dfourth2=cat(2,dfourth1,h);


r1=det(e);
r2=det(dfirst2);
r3=det(dsecond2);
r4=det(dthird2);
r5=det(dfourth2);

x1=r2/r1;
x2=r3/r1;
x3=r4/r1;
x4=r5/r1;

x=e\h;

set(app.Label, 'Text', num2str(x1));
set(app.Label_2, 'Text', num2str(x2));
set(app.Label_3, 'Text', num2str(x3));
set(app.Label_4, 'Text', num2str(x4));
set(app.Label_6, 'Text', num2str(x'));
        end

        % Button pushed function: X5Button
        function X5ButtonPushed(app, event)
            s1=app.EditField.Value;
            s2=app.EditField_2.Value;
            s3=app.EditField_3.Value;
            s4=app.EditField_4.Value;
            s5=app.EditField_5.Value;
            s6=app.EditField_6.Value;
            s7=app.EditField_7.Value;
            s8=app.EditField_8.Value;
            s9=app.EditField_9.Value;
            s10=app.EditField_10.Value;
            s11=app.EditField_11.Value;
            s12=app.EditField_12.Value;
            s13=app.EditField_13.Value;
            s14=app.EditField_14.Value;
            s15=app.EditField_15.Value;
            s16=app.EditField_16.Value;
            s17=app.EditField_17.Value;
            s18=app.EditField_18.Value;
            s19=app.EditField_19.Value;
            s20=app.EditField_20.Value;
            s21=app.EditField_21.Value;
            s22=app.EditField_22.Value;
            s23=app.EditField_23.Value;
            s24=app.EditField_24.Value;
            s25=app.EditField_25.Value;
            s26=app.EditField_26.Value;
            s27=app.EditField_27.Value;
            s28=app.EditField_28.Value;
            s29=app.EditField_29.Value;
            s30=app.EditField_30.Value;
            % a1=str2num(s1);
            % a2=str2num(s2);
            % a3=str2num(s3);
            % a4=str2num(s4);
            % a5=str2num(s5);
            % a6=str2num(s6);
            % a7=str2num(s7);
            % a8=str2num(s8);
            % a9=str2num(s9);
            % a10=str2num(s10);
            % a11=str2num(s11);
            % a12=str2num(s12);
            % a13=str2num(s13);
            % a14=str2num(s14);
            % a15=str2num(s15);
            % a16=str2num(s16);
            % a17=str2num(s17);
            % a18=str2num(s18);
            % a19=str2num(s19);
            % a20=str2num(s20);
            % a21=str2num(s21);
            % a22=str2num(s22);
            % a23=str2num(s23);
            % a24=str2num(s24);
            % a25=str2num(s25);
            % a26=str2num(s26);
            % a27=str2num(s27);
            % a28=str2num(s28);
            % a29=str2num(s29);
            % a30=str2num(s30);
            c1=cat(2,s1,s2);
            c2=cat(2,c1,s3);
            c3=cat(2,c2,s4);
            c4=cat(2,c3,s5);
            v1=cat(2,s7,s8);
            v2=cat(2,v1,s9);
            v3=cat(2,v2,s10);
            v4=cat(2,v3,s11);
            b1=cat(2,s13,s14);
            b2=cat(2,b1,s15);
            b3=cat(2,b2,s16);
            b4=cat(2,b3,s17);
            n1=cat(2,s19,s20);
            n2=cat(2,n1,s21);
            n3=cat(2,n2,s22);
            n4=cat(2,n3,s23);
            m1=cat(2,s25,s26);
            m2=cat(2,m1,s27);
            m3=cat(2,m2,s28);
            m4=cat(2,m3,s29);
            q=cat(1,c4,v4);
            w=cat(1,q,b4);
            e=cat(1,w,n4);
            r=cat(1,e,m4);
            f=cat(1,s6,s12);
            g=cat(1,f,s18);
            h=cat(1,g,s24);
            j=cat(1,h,s30);
            d=cat(1,s1,s7);
            d1=cat(1,d,s13);
            d2=cat(1,d1,s19);
            d3=cat(1,d2,s25);
            k=cat(1,s2,s8);
            k1=cat(1,k,s14);
            k2=cat(1,k1,s20);
            k3=cat(1,k2,s26);
            p=cat(1,s3,s9);
            p1=cat(1,p,s15);
            p2=cat(1,p1,s21);
            p3=cat(1,p2,s27);
            t=cat(1,s4,s10);
            t1=cat(1,t,s16);
            t2=cat(1,t1,s22);
            t3=cat(1,t2,s28);
            u=cat(1,s5,s11);
            u1=cat(1,u,s17);
            u2=cat(1,u1,s23);
            u3=cat(1,u2,s29);
            dfirst=cat(2,j,k3);
            dfirst1=cat(2,dfirst,p3);
            dfirst2=cat(2,dfirst1,t3);
            dfirst3=cat(2,dfirst2,u3);
            dsecond=cat(2,d3,j);
            dsecond1=cat(2,dsecond,p3);
            dsecond2=cat(2,dsecond1,t3);
            dsecond3=cat(2,dsecond2,u3);
            dthird=cat(2,d3,k3);
            dthird1=cat(2,dthird,j);
            dthird2=cat(2,dthird1,t3);
            dthird3=cat(2,dthird2,u3);
            dfourth=cat(2,d3,k3);
            dfourth1=cat(2,dfourth,p3);
            dfourth2=cat(2,dfourth1,j);
            dfourth3=cat(2,dfourth2,u3);
            dfifth=cat(2,d3,k3);
            dfifth1=cat(2,dfifth,p3);
            dfifth2=cat(2,dfifth1,t3);
            dfifth3=cat(2,dfifth2,j);
            r1=det(r);
            r2=det(dfirst3);
            r3=det(dsecond3);
            r4=det(dthird3);
            r5=det(dfourth3);
            r6=det(dfifth3);
            x1=r2/r1;
            x2=r3/r1;
            x3=r4/r1;
            x4=r5/r1;
            x5=r6/r1;
            x=r\j;
            % set(app.Label7, 'Text', cell2mat(x));
            set(app.Label, 'Text', num2str(x1));
            set(app.Label_2, 'Text', num2str(x2));
            set(app.Label_3, 'Text', num2str(x3));
            set(app.Label_4, 'Text', num2str(x4));
            set(app.Label_5, 'Text', num2str(x5));
            set(app.Label_6, 'Text', num2str(x'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_19 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_25 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1+';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_2Label = uilabel(app.UIFigure);
            app.X1EditField_2Label.Text = 'X1+';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_3Label = uilabel(app.UIFigure);
            app.X1EditField_3Label.Text = 'X1+';
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_4Label = uilabel(app.UIFigure);
            app.X1EditField_4Label.Text = 'X1+';
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_5Label = uilabel(app.UIFigure);
            app.X1EditField_5Label.Text = 'X1+';
            app.EditField_26 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2+';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_2Label = uilabel(app.UIFigure);
            app.X2EditField_2Label.Text = 'X2+';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_3Label = uilabel(app.UIFigure);
            app.X2EditField_3Label.Text = 'X2+';
            app.EditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_4Label = uilabel(app.UIFigure);
            app.X2EditField_4Label.Text = 'X2+';
            app.EditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField_5Label = uilabel(app.UIFigure);
            app.X2EditField_5Label.Text = 'X2+';
            app.EditField_27 = uieditfield(app.UIFigure, 'numeric');
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X3+';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField_2Label = uilabel(app.UIFigure);
            app.X3EditField_2Label.Text = 'X3+';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField_3Label = uilabel(app.UIFigure);
            app.X3EditField_3Label.Text = 'X3+';
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField_4Label = uilabel(app.UIFigure);
            app.X3EditField_4Label.Text = 'X3+';
            app.EditField_22 = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField_5Label = uilabel(app.UIFigure);
            app.X3EditField_5Label.Text = 'X3+';
            app.EditField_28 = uieditfield(app.UIFigure, 'numeric');
            app.X4EditFieldLabel = uilabel(app.UIFigure);
            app.X4EditFieldLabel.Text = 'X4+';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField_2Label = uilabel(app.UIFigure);
            app.X4EditField_2Label.Text = 'X4+';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField_3Label = uilabel(app.UIFigure);
            app.X4EditField_3Label.Text = 'X4+';
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField_4Label = uilabel(app.UIFigure);
            app.X4EditField_4Label.Text = 'X4+';
            app.EditField_23 = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField_5Label = uilabel(app.UIFigure);
            app.X4EditField_5Label.Text = 'X4+';
            app.EditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.X5EditFieldLabel = uilabel(app.UIFigure);
            app.X5EditFieldLabel.Text = 'X5 =';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.X5EditField_2Label = uilabel(app.UIFigure);
            app.X5EditField_2Label.Text = 'X5 =';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.X5EditField_3Label = uilabel(app.UIFigure);
            app.X5EditField_3Label.Text = 'X5 =';
            app.EditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.X5EditField_4Label = uilabel(app.UIFigure);
            app.X5EditField_4Label.Text = 'X5 =';
            app.EditField_24 = uieditfield(app.UIFigure, 'numeric');
            app.X5EditField_5Label = uilabel(app.UIFigure);
            app.X5EditField_5Label.Text = 'X5 =';
            app.EditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.X2Button = uibutton(app.UIFigure, 'push');
            app.X2Button.ButtonPushedFcn = createCallbackFcn(app, @X2ButtonPushed, true);
            app.X2Button.Text = '2X2';
            app.X3Button = uibutton(app.UIFigure, 'push');
            app.X3Button.ButtonPushedFcn = createCallbackFcn(app, @X3ButtonPushed, true);
            app.X3Button.Text = '3X3';
            app.X4Button = uibutton(app.UIFigure, 'push');
            app.X4Button.ButtonPushedFcn = createCallbackFcn(app, @X4ButtonPushed, true);
            app.X4Button.Text = '4X4';
            app.X5Button = uibutton(app.UIFigure, 'push');
            app.X5Button.ButtonPushedFcn = createCallbackFcn(app, @X5ButtonPushed, true);
            app.X5Button.Text = {'5X5'; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Вектор решений:';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'Решение методом Крамера:';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Решение методом Гаусса ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end